classdef gameplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PickyouravatarDropDown_2       matlab.ui.control.DropDown
        PickyouravatarDropDown_2Label  matlab.ui.control.Label
        PickyouravatarDropDown         matlab.ui.control.DropDown
        PickyouravatarDropDownLabel    matlab.ui.control.Label
        ScoreBreakdownLabel            matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        GameInfoLabel                  matlab.ui.control.Label
        Player1sturnLabel              matlab.ui.control.Label
        five                           matlab.ui.control.Image
        six                            matlab.ui.control.Image
        four                           matlab.ui.control.Image
        three                          matlab.ui.control.Image
        two                            matlab.ui.control.Image
        one                            matlab.ui.control.Image
        Player2nameEditField           matlab.ui.control.EditField
        Player2nameEditFieldLabel      matlab.ui.control.Label
        Player1nameEditField           matlab.ui.control.EditField
        Player1nameEditFieldLabel      matlab.ui.control.Label
        RolldiceButton                 matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        BankPointsButton               matlab.ui.control.Button
        RulesButton                    matlab.ui.control.Button
        SetAsideButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        OnlineButton                   matlab.ui.control.Button
        LocalButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        game      Game % Description
        dice 
    end
    
    methods (Access = public)
        function app = updateTable(app)
            app.UITable.Data = [app.game.p1.name app.game.p1.score; app.game.p1.name app.game.p2.score];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.game = Game("P1", "P2");
            app.dice = DiceArray();
            %for i = 1:6
            %    app.diceList(i) = Dice();
            %end
            app.UITable.Data = [app.game.p1.name app.game.p1.score; app.game.p1.name app.game.p2.score];
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            rules
        end

        % Button pushed function: RolldiceButton
        function RolldiceButtonPushed(app, event)

            % Update GUI images
            for i = 1:7
                rolls = randi(6, 1, 6);
                app.one.ImageSource = string(fullfile('images','dice', char("dice" + rolls(1) + ".png")));
                app.two.ImageSource = string(fullfile('images','dice', char("dice" + rolls(2) + ".png")));
                app.three.ImageSource = string(fullfile('images','dice', char("dice" + rolls(3) + ".png")));
                app.four.ImageSource = string(fullfile('images','dice', char("dice" + rolls(4) + ".png")));
                app.five.ImageSource = string(fullfile('images','dice', char("dice" + rolls(5) + ".png")));
                app.six.ImageSource = string(fullfile('images','dice', char("dice" + rolls(6) + ".png")));
                pause(0.1);
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.LocalButton = uibutton(app.UIFigure, 'push');
            app.LocalButton.Text = 'Local';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.Text = 'Online';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player Name'; 'Score'};
            app.UITable.RowName = {};
            app.SetAsideButton = uibutton(app.UIFigure, 'push');
            app.SetAsideButton.Text = 'Set Aside';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.BankPointsButton = uibutton(app.UIFigure, 'push');
            app.BankPointsButton.Text = 'Bank Points';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.Text = 'Restart';
            app.RolldiceButton = uibutton(app.UIFigure, 'push');
            app.RolldiceButton.ButtonPushedFcn = createCallbackFcn(app, @RolldiceButtonPushed, true);
            app.RolldiceButton.Text = 'Roll dice';
            app.Player1nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player1nameEditFieldLabel.Text = 'Player 1 name:';
            app.Player1nameEditField = uieditfield(app.UIFigure, 'text');
            app.Player2nameEditFieldLabel = uilabel(app.UIFigure);
            app.Player2nameEditFieldLabel.Text = 'Player 2 name:';
            app.Player2nameEditField = uieditfield(app.UIFigure, 'text');
            app.one = uiimage(app.UIFigure);
            app.one.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice1.png');
            app.two = uiimage(app.UIFigure);
            app.two.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice2.png');
            app.three = uiimage(app.UIFigure);
            app.three.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice3.png');
            app.four = uiimage(app.UIFigure);
            app.four.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice4.png');
            app.six = uiimage(app.UIFigure);
            app.six.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice6.png');
            app.five = uiimage(app.UIFigure);
            app.five.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice5.png');
            app.Player1sturnLabel = uilabel(app.UIFigure);
            app.Player1sturnLabel.Text = 'Player 1''s turn';
            app.GameInfoLabel = uilabel(app.UIFigure);
            app.GameInfoLabel.Text = 'Game Info:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'scoring.png');
            app.ScoreBreakdownLabel = uilabel(app.UIFigure);
            app.ScoreBreakdownLabel.Text = 'Score Breakdown';
            app.PickyouravatarDropDownLabel = uilabel(app.UIFigure);
            app.PickyouravatarDropDownLabel.Text = 'Pick your avatar';
            app.PickyouravatarDropDown = uidropdown(app.UIFigure);
            app.PickyouravatarDropDown_2Label = uilabel(app.UIFigure);
            app.PickyouravatarDropDown_2Label.Text = 'Pick your avatar';
            app.PickyouravatarDropDown_2 = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end