classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        ChangeBackgroundDropDown       matlab.ui.control.DropDown
        ChangeBackgroundDropDownLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Player2Label                   matlab.ui.control.Label
        Player1Label                   matlab.ui.control.Label
        FarkleLabel                    matlab.ui.control.Label
        GameInfoEditField              matlab.ui.control.EditField
        GameInfoEditFieldLabel         matlab.ui.control.Label
        Lamp2                          matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        five                           matlab.ui.control.Image
        six                            matlab.ui.control.Image
        four                           matlab.ui.control.Image
        three                          matlab.ui.control.Image
        two                            matlab.ui.control.Image
        one                            matlab.ui.control.Image
        RollDiceButton                 matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        BankPointsButton               matlab.ui.control.Button
        RulesButton                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        OnlineButton                   matlab.ui.control.Button
        LocalButton                    matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        game      Game
        dice 
        rolls
        validDice
        triples
        numSelected

        turn
        turnNumber = 1
        p1name
        p2name
        p1score = 0;
        p2score = 0;
        msgData = [];
        msgIndex = 1;

        channelID = 2061872;
        writeAPI = '2ELG9UY53CY4TOVS';  
        readAPI = 'AU8BCNTQ49XS1W9G';

        online = false;
        player
        dialogBox
    end
    
    methods (Access = public)
        function app = updateTable(app)
            if (app.online)
                pause(1);
                app.p1score = thingSpeakRead(app.channelID, "Fields", 3, "ReadKey", app.readAPI);
                disp(app.p1score);
                pause(1);
                app.p2score = thingSpeakRead(app.channelID, "Fields", 4, "ReadKey", app.readAPI);
                disp(app.p2score);
                pause(1);
            end
            app.UITable.Data = [app.p1name app.p1score; app.p2name app.p2score];
        end

        function app = updateDiceImages(app, arr)
            app.one.ImageSource = string(fullfile('images','dice', char("dice" + arr(1) + ".png")));
            app.two.ImageSource = string(fullfile('images','dice', char("dice" + arr(2) + ".png")));
            app.three.ImageSource = string(fullfile('images','dice', char("dice" + arr(3) + ".png")));
            app.four.ImageSource = string(fullfile('images','dice', char("dice" + arr(4) + ".png")));
            app.five.ImageSource = string(fullfile('images','dice', char("dice" + arr(5) + ".png")));
            app.six.ImageSource = string(fullfile('images','dice', char("dice" + arr(6) + ".png")));

            % TO DO: Update selection background highlight.
            if (app.dice.isSelected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
            if (app.dice.isSelected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
            if (app.dice.isSelected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
            if (app.dice.isSelected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
            if (app.dice.isSelected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
            if (app.dice.isSelected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
        end
        
        function app = farkle(app)
            app.Label.Text = "Farkle!";
            app.dice = app.dice.unselectAll();
            app.updateDiceImages(app.dice.allValues());
            pause(1);
            app.changeTurn();
            app.Label.Text = "Play";
            if (~app.online)
                app.RollDiceButton.Enable = 'on';
            end
            app.Label.Text = "";
        end
        
        function app = changeTurn(app)
            app.BankPointsButton.Enable = 'off';
            app.turn = app.turn*-1;
            if (app.online)
                pause(1);
                if (app.player == 1)
                    thingSpeakWrite(app.channelID2, 'Fields', 1, 'Values', app.message1, 'WriteKey',app.writeAPI2);
                    %app.writeNumber([5, 7], [app.turn, app.message]);
                else
                    thingSpeakWrite(app.channelID2, 'Fields', 2, 'Values', app.message2, 'WriteKey',app.writeAPI2);

                    %app.writeNumber([5, 8], [app.turn, app.message]);
                end
                
                disp(thingSpeakWrite(app.channelID, 'Fields', [1, 2, 3, 4, 5, 6], 'Values',[app.p1name app.p2name, app.p1score, app.p2score, app.turn, 0], 'WriteKey',app.writeAPI));
                if (app.turn ~= app.player)
                    app.GameInfoEditField.Value = "Waiting for other player.";
                    app.RollDiceButton.Enable = 'off';
                    app.waitForMessage(5, app.player);

                    app.updateTable(); 
                    app.turn = app.turn*-1;
                    app.RollDiceButton.Enable = 'on';
                    app.GameInfoEditField.Value = "Your turn.";
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
            
            app.dice = app.dice.unselectAll();
            app.dice = app.dice.updateValues(randi(6, 1, 6));
            
            app.updateDiceImages(app.dice.allValues());
            if (app.turn == 1)
                app.Lamp.Enable = 'on';
                app.Lamp2.Enable = 'off';
            else
                app.Lamp2.Enable = 'on';
                app.Lamp.Enable = 'off';
            end    
        end
        
        function app = hotDice(app)
            app.Label.Text = "Hot Dice";
            %app.changeTurn();
            app.dice = app.dice.unselectAll();
            app.RollDiceButton.Enable = 'on';
            pause(2);
            app.Label.Text = "";
        end

        function rollDice(app, enableBank)
            selected = app.dice.selectedIndices();
      
            for i = 1:7
                for j = 1:6
                    dicenums = app.dice.allValues();
                    if ~ismember(i, selected)
                        dicenums(i) = randi(6);
                    end
                end
                app.updateDiceImages(dicenums);
                pause(0.07)
            end

            for i = 1:6
               if ~ismember(i, selected)
                   app.rolls(i) = randi(6);           
               end
            end

            app.updateDiceImages(app.rolls);
            
            % Update the DiceArray
            app.dice = app.dice.updateValues(app.rolls);

            % Generate melds.   
            [score, app.triples] = app.dice.generateMelds(app.dice.unselectedValues());
            app.validDice = [app.validDice, app.triples, 1, 5];

            if (enableBank)
                app.BankPointsButton.Enable = 'on';
            else
                app.BankPointsButton.Enable = 'off';
            end

            if score == 0
                app.farkle();
            end

            %app.updateTable();
            app.RollDiceButton.Enable = 'off';
        end
        
        function [] = waitForMessage(app, field, num)
            msgval = 0;
            disp("Waiting for message " + string(num) + "from field " + string(field));
            while msgval ~= num
                msgval = thingSpeakRead(app.channelID, "Fields", field, "ReadKey",app.readAPI);
                pause(5);
            end
        end
        
        function [] = writeNumber(app, field, num)
            pause(1);
            fields = [1, 2, 3, 4, 5, 6];
            data = [app.p1name, app.p2name, app.p1score, app.p2score, app.turn, 0];
            data(field) = num;
            disp(data);
            disp("I am writing number" + string(num) + "to field " + string(field));
            thingSpeakWrite(app.channelID, 'Fields', fields, 'Values', data, 'WriteKey',app.writeAPI);
        end

        function num = readNumber(app, field)
            num = thingSpeakRead(app.channelID, "Fields", field, "ReadKey", app.readAPI);
            disp("Read a number " + string(num) + "from field " + field);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p1name = "p1";
            app.p2name = "p2"; 
            app.p1score = 0;
            app.p2score = 0;
            app.dice = DiceArray(); %
            app.updateTable();
            app.rolls = randi(6, 1, 6);
            app.updateDiceImages(app.rolls);
            app.validDice = [];
            app.numSelected = 0;
            app.turn = 1;
            app.Lamp.Enable = 'on';
            app.Lamp2.Enable = 'off';
            app.BankPointsButton.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            %app.GameInfoEditField.Enable = 'off';
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            rules
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)

            filepathAudio = fileparts(mfilename('Farkle'));
            [read_audio, speed_audio] = audioread(fullfile(filepathAudio, 'Audio','rolling_dice_audio.mp3'));
            play_Audio = audioplayer(read_audio, speed_audio);
            play(play_Audio);

            % Rolling the unselected dice:
            app.rollDice(true);
            
        end

        % Image clicked function: one
        function oneImageClicked(app, event)
            if (ismember(app.dice.getValue(1), app.validDice) && ~app.dice.isSelected(1))
                app.dice = app.dice.changeSelection(1);
                if (app.dice.isSelected(1)); app.one.BackgroundColor = 'r'; else; app.one.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(1), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(1))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: two
        function twoImageClicked(app, event)
            if (ismember(app.dice.getValue(2), app.validDice) && ~app.dice.isSelected(2))
            app.dice = app.dice.changeSelection(2);
            if (app.dice.isSelected(2)); app.two.BackgroundColor = 'r'; else; app.two.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(2), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(2))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: three
        function threeImageClicked(app, event)
            if (ismember(app.dice.getValue(3), app.validDice) && ~app.dice.isSelected(3))
            app.dice = app.dice.changeSelection(3);
            if (app.dice.isSelected(3)); app.three.BackgroundColor = 'r'; else; app.three.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(3), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(3))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: four
        function fourImageClicked(app, event)
            if (ismember(app.dice.getValue(4), app.validDice) && ~app.dice.isSelected(4))
            app.dice = app.dice.changeSelection(4);
            if (app.dice.isSelected(4)); app.four.BackgroundColor = 'r'; else; app.four.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(4), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(4))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: five
        function fiveImageClicked(app, event)
            if (ismember(app.dice.getValue(5), app.validDice) && ~app.dice.isSelected(5))
            app.dice = app.dice.changeSelection(5);
            if (app.dice.isSelected(5)); app.five.BackgroundColor = 'r'; else; app.five.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(5), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(5))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Image clicked function: six
        function sixImageClicked(app, event)
            if (ismember(app.dice.getValue(6), app.validDice) && ~app.dice.isSelected(6))
            app.dice = app.dice.changeSelection(6);
            if (app.dice.isSelected(6)); app.six.BackgroundColor = 'r'; else; app.six.BackgroundColor = 'none'; end
                if (ismember(app.dice.getValue(6), app.triples))
                    if numel(find(app.dice.selectedValues() == app.dice.getValue(6))) == 3
                        app.RollDiceButton.Enable = 'on';
                    end
                else
                    app.RollDiceButton.Enable = 'on';
                end
            end
        end

        % Button pushed function: BankPointsButton
        function BankPointsButtonPushed(app, event)

            [y, Fs] = audioread('banking_points.mp3');
            sound(y, Fs);

            [score, triplets, numFives, numOnes] = app.dice.generateMelds(app.dice.allValues());
           
            if app.turn == 1
                app.p1score = app.p1score + score;
            else
                app.p2score = app.p2score + score;
            end
            if (app.online)
                    if (app.player == 1)
                        app.writeNumber(3, app.p1score);
                    else
                        app.writeNumber(4, app.p2score);
                    end
            end
            app.updateTable();
            
            if (~app.online)
                app.RollDiceButton.Enable = 'on';
            end

            allVals = app.dice.allValues();
            if ((numel(triplets)*3 + numFives + numOnes == 6) || all(allVals./allVals(1) == 1))
                app.hotDice();
                return;
            end
            %app.p1score = app.p1score + 9000;
            if (app.p1score >= 10000)
                app.dialogBox = app1(app, app.p1score, app.p1name);
            end

            if (app.p2score >= 10000)
                app.dialogBox = app1(app, app.p2score, app.p2name);
            end

            app.changeTurn();
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            
            app.p1name = "p1";
            app.p2name = "p2"; 
            app.p1score = 0;
            app.p2score = 0;
            app.dice = DiceArray(); %
            app.updateTable();
            app.rolls = randi(6, 1, 6);
            app.updateDiceImages(app.rolls);
            app.validDice = [];
            app.numSelected = 0;
            app.turn = 1;
            app.Lamp.Enable = 'on';
            app.Lamp2.Enable = 'off';
            app.BankPointsButton.Enable = 'off';
            app.RollDiceButton.Enable = 'off';
            app.LocalButton.Enable = 'on';
            app.OnlineButton.Enable = 'on';
        end

        % Button pushed function: OnlineButton
        function OnlineButtonPushed(app, event)
            name = inputdlg("Enter your name:");
            app.LocalButton.Enable = "off";
            app.OnlineButton.Enable = "off";
            app.online = true;
            if (thingSpeakRead(app.channelID, "Fields", 6, "ReadKey",app.readAPI) == 1)

                app.player = -1; % Designate self as player 2
                app.GameInfoEditField.Value = "You are P2";
                app.p2name = name;
                app.Player2Label.Text = app.p2name;
                app.writeNumber(6, 2);

                app.waitForMessage(5, app.player);
                tbl = thingSpeakRead(app.channelID, "Fields", 1, "ReadKey", app.readAPI, 'OutputFormat','table');
                app.p1name = string(tbl{1, 2});
                app.Player1Label.Text = app.p1name;
                app.p1score = thingSpeakRead(app.channelID, "Fields", 3, "ReadKey", app.readAPI);
                app.UITable.Data = [app.p1name app.p1score; app.p2name app.p2score];
                app.GameInfoEditField.Value = "Your turn.";
                app.turn = -1;
                app.RollDiceButton.Enable='on';
            else
                app.writeNumber(6, 1);
                app.player = 1; %Designate self as player 1
                % Display waiting for connection status.
                app.GameInfoEditField.Value = "You are P1";
                app.p1name = name;
                app.Player1Label.Text = app.p1name;
                app.waitForMessage(6, 2);
                tbl = thingSpeakRead(app.channelID, "Fields", 2, "ReadKey", app.readAPI, 'OutputFormat','table');
                app.p2name = string(tbl{1, 2});
                app.Player2Label.Text = app.p2name;
                app.GameInfoEditField.Value = "P2 has connected.";

                app.turn = 1;
                app.RollDiceButton.Enable ="on";
            end
        end

        % Button pushed function: LocalButton
        function LocalButtonPushed(app, event)
            app.OnlineButton.Enable = "off";
            app.LocalButton.Enable = "off";
            app.RollDiceButton.Enable = "on";
            prompt = {'Player 1 name:','Player 2 name:'};
            dlgtitle = 'Set names';
            dims = [1 35];
            definput = {'P1', 'P2'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            app.p1name = answer(1);
            app.p2name = answer(2);
            avatar(app, 1);
            pause(5);
            avatar(app, -1);
            app.updateTable();
        end

        % Value changed function: ChangeBackgroundDropDown
        function ChangeBackgroundDropDownValueChanged(app, event)
            value = app.ChangeBackgroundDropDown.Value;
            pathToMLAPP = fileparts(mfilename('fullpath'));
            switch (value)
                case ('Orange')
                    app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'bg', 'wall2.jpeg');
                case ('Blue')
                    app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'bg', 'blue.jpeg');
                case ('Light Orange')
                    app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'bg', 'lightorange.jpeg');
                case ('Pink')
                    app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'bg', 'pinklight.jpeg');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'bg', 'wall2.jpeg');
            app.LocalButton = uibutton(app.UIFigure, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @LocalButtonPushed, true);
            app.LocalButton.Text = 'Local';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineButtonPushed, true);
            app.OnlineButton.Text = 'Online';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player Name'; 'Score'};
            app.UITable.RowName = {};
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.BankPointsButton = uibutton(app.UIFigure, 'push');
            app.BankPointsButton.ButtonPushedFcn = createCallbackFcn(app, @BankPointsButtonPushed, true);
            app.BankPointsButton.Text = 'Bank Points';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.one = uiimage(app.UIFigure);
            app.one.ImageClickedFcn = createCallbackFcn(app, @oneImageClicked, true);
            app.one.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice1.png');
            app.two = uiimage(app.UIFigure);
            app.two.ImageClickedFcn = createCallbackFcn(app, @twoImageClicked, true);
            app.two.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice2.png');
            app.three = uiimage(app.UIFigure);
            app.three.ImageClickedFcn = createCallbackFcn(app, @threeImageClicked, true);
            app.three.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice3.png');
            app.four = uiimage(app.UIFigure);
            app.four.ImageClickedFcn = createCallbackFcn(app, @fourImageClicked, true);
            app.four.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice4.png');
            app.six = uiimage(app.UIFigure);
            app.six.ImageClickedFcn = createCallbackFcn(app, @sixImageClicked, true);
            app.six.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice6.png');
            app.five = uiimage(app.UIFigure);
            app.five.ImageClickedFcn = createCallbackFcn(app, @fiveImageClicked, true);
            app.five.ImageSource = fullfile(pathToMLAPP, 'images', 'dice', 'dice5.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.GameInfoEditFieldLabel = uilabel(app.UIFigure);
            app.GameInfoEditFieldLabel.Text = 'Game Info';
            app.GameInfoEditField = uieditfield(app.UIFigure, 'text');
            app.FarkleLabel = uilabel(app.UIFigure);
            app.FarkleLabel.Text = 'Farkle';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'rules2.jpg');
            app.ChangeBackgroundDropDownLabel = uilabel(app.UIFigure);
            app.ChangeBackgroundDropDownLabel.Text = 'Change Background';
            app.ChangeBackgroundDropDown = uidropdown(app.UIFigure);
            app.ChangeBackgroundDropDown.Items = {'Orange', 'Pink', 'Light Orange', 'Blue'};
            app.ChangeBackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeBackgroundDropDownValueChanged, true);
            app.ChangeBackgroundDropDown.Value = 'Orange';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'p1', 'monster.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'avatars', 'p2', 'boy.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end